classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.StateButton
        OutputLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        recording = false
        expression = ""
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonPushed(app, event)
            if app.recording
                app.Button.Icon = "mic.svg";
                app.recording = false;

                if app.expression == ""
                    app.OutputLabel.Text = "Click the Record button to start recording...";
                end
            else
                app.Button.Icon = "stop.svg";
                app.recording = true;

                if app.expression == ""
                    app.OutputLabel.Text = "...";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Click the Record button to start recording...';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'mic.svg');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end